classdef appForFOC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIFigureGrid                  matlab.ui.container.GridLayout
        ToolstripPanel                matlab.ui.container.Panel
        SystemLogPanel                matlab.ui.container.Panel
        SystemLogGrid                 matlab.ui.container.GridLayout
        SystemLog                     slrealtime.ui.control.SystemLog
        RunOnTarget                   matlab.ui.container.Panel
        RunOnTargetGrid               matlab.ui.container.GridLayout
        StopTimeLabel                 matlab.ui.control.Label
        StartStopButton               slrealtime.ui.control.StartStopButton
        StopTimeEditField             slrealtime.ui.control.StopTimeEditField
        Prepare                       matlab.ui.container.Panel
        PrepareGrid                   matlab.ui.container.GridLayout
        LoadButton                    slrealtime.ui.control.LoadButton
        ConnectToTarget               matlab.ui.container.Panel
        ConnectToTargetGrid           matlab.ui.container.GridLayout
        TargetSelector                slrealtime.ui.control.TargetSelector
        ConnectButton                 slrealtime.ui.control.ConnectButton
        TabGroup                      matlab.ui.container.TabGroup
        AllComponentsTab              matlab.ui.container.Tab
        AllComponentsGridLayout       matlab.ui.container.GridLayout
        AllAddedComponentsGridLayout  matlab.ui.container.GridLayout
        AddedUIComponentsGridLayout   matlab.ui.container.GridLayout
        MotorSpeedGauge               matlab.ui.control.Gauge
        PWMEnable                     matlab.ui.control.ToggleSwitch
        SpeedRefKnob                  matlab.ui.control.Knob
        CurrentsAxes                  matlab.ui.control.UIAxes
        MotorAngleAxes                matlab.ui.control.UIAxes
        SpeedAxes                     matlab.ui.control.UIAxes
        StatusBarPanel                matlab.ui.container.Panel
        StatusBarGrid                 matlab.ui.container.GridLayout
        StatusBar                     slrealtime.ui.control.StatusBar
    end

    
    methods (Static, Access = private)
        function out_val = convToDouble(in_val)
            if isnumeric(in_val)
                out_val = double(in_val);
            else
                out_val = str2double(in_val);
            end
        end
        
        function out_val = convToString(in_val)
            if isnumeric(in_val)
                out_val = string(num2str(in_val));
            else
                out_val = string(in_val);
            end
        end
        
        function out_val = convToScalarLogical(in_val)
            if isnumeric(in_val)
                out_val = any(logical(in_val));
            else
                out_val = any(logical(str2double(in_val)));
            end
        end

        function out_val = convertLabelToValue(in_val, values, labels)
            out_val = values(find(strcmp(in_val, labels))); %#ok
        end

        function out_val = convertValueToLabel(in_val, values, labels)
            out_val = labels(find(arrayfun(@(x)isequal(in_val, x), values))); %#ok
        end

        function out_data = displayBlockCB(in_data)
            if isnumeric(in_data)
                out_data = string(num2str(in_data));
            else
                out_data = string(in_data);
            end
        end
        
        function out_val = lampBlockCB(in_val, states, default)
            values = states{1};
            colors = states{2};
            
            in_val = in_val(end);
            out_val = default;
            
            for i=1:length(values)
                if in_val == values(i)
                    out_val = colors(i,:);
                    return;
                end
            end
        end

        function out_val = convToOnOff(in_val)
            if ~islogical(in_val) && ~isnumeric(in_val)
                in_val = str2num(in_val); %#ok
            end
            if logical(in_val)
                out_val = 'On';
            else
                out_val = 'Off';
            end
        end

        function out_val = convFromOnOff(in_val)
            if strcmp(in_val, 'On')
                out_val = logical(true);
            else
                out_val = logical(false);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            targetSelector = app.TargetSelector;

            app.ConnectButton.TargetSource = targetSelector;
            app.LoadButton.TargetSource = targetSelector;
            app.StartStopButton.TargetSource = targetSelector;
            app.StopTimeEditField.TargetSource = targetSelector;
            app.SystemLog.TargetSource = targetSelector;
            app.StatusBar.TargetSource = targetSelector;

            hInst = slrealtime.Instrument();

            legend(app.CurrentsAxes, 'Interpreter', 'none');
            legend(app.MotorAngleAxes, 'Interpreter', 'none');
            legend(app.SpeedAxes, 'Interpreter', 'none');
            app.MotorSpeedGauge.Tooltip = 'MotorSpeed';


            hInst.connectLine(app.SpeedAxes, 'SpeedRef');
            hInst.connectLine(app.SpeedAxes, 'Speedfb');
            hInst.connectLine(app.CurrentsAxes, 'Ia');
            hInst.connectLine(app.CurrentsAxes, 'Ib');
            hInst.connectLine(app.CurrentsAxes, 'Ic');
            hInst.connectScalar(app.MotorSpeedGauge, 'MotorSpeed');
            hInst.connectLine(app.MotorAngleAxes, 'MotorAngle');

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.SpeedRefKnob;
            slrtcomp.BlockPath = '';
            slrtcomp.ParameterName = 'SpeedRef';
            slrtcomp.ConvertToComponent = @app.convToDouble;

            slrtcomp = slrealtime.ui.tool.ParameterTuner(app.UIFigure, 'TargetSource', targetSelector);
            slrtcomp.Component = app.PWMEnable;
            slrtcomp.BlockPath = 'FOCandPMSM/PWM Enable';
            slrtcomp.ParameterName = 'Value';
            slrtcomp.ConvertToComponent = @app.convToOnOff;
            slrtcomp.ConvertToTarget = @app.convFromOnOff;

            slrtInstrumentsComponent = slrealtime.ui.tool.InstrumentManager(app.UIFigure, 'TargetSource', targetSelector);
            slrtInstrumentsComponent.Instruments = hInst;
                    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigureGrid = uigridlayout(app.UIFigure);
            app.StatusBarPanel = uipanel(app.UIFigureGrid);
            app.StatusBarGrid = uigridlayout(app.StatusBarPanel);
            app.StatusBar = slrealtime.ui.control.StatusBar(app.StatusBarGrid);
            app.TabGroup = uitabgroup(app.UIFigureGrid);
            app.AllComponentsTab = uitab(app.TabGroup);
            app.AllComponentsTab.Title = 'Signals and Parameters';
            app.AllComponentsGridLayout = uigridlayout(app.AllComponentsTab);
            app.AllAddedComponentsGridLayout = uigridlayout(app.AllComponentsGridLayout);
            app.AddedUIComponentsGridLayout = uigridlayout(app.AllAddedComponentsGridLayout);
            app.SpeedAxes = uiaxes(app.AddedUIComponentsGridLayout);
            app.MotorAngleAxes = uiaxes(app.AddedUIComponentsGridLayout);
            app.CurrentsAxes = uiaxes(app.AddedUIComponentsGridLayout);
            app.SpeedRefKnob = uiknob(app.AddedUIComponentsGridLayout, 'continuous');
            app.SpeedRefKnob.Limits = [0 1.5];
            app.PWMEnable = uiswitch(app.AddedUIComponentsGridLayout, 'toggle');
            app.MotorSpeedGauge = uigauge(app.AddedUIComponentsGridLayout, 'circular');
            app.MotorSpeedGauge.Limits = [0 500];
            app.ToolstripPanel = uipanel(app.UIFigureGrid);
            app.ConnectToTarget = uipanel(app.ToolstripPanel);
            app.ConnectToTargetGrid = uigridlayout(app.ConnectToTarget);
            app.ConnectButton = slrealtime.ui.control.ConnectButton(app.ConnectToTargetGrid);
            app.TargetSelector = slrealtime.ui.control.TargetSelector(app.ConnectToTargetGrid);
            app.Prepare = uipanel(app.ToolstripPanel);
            app.PrepareGrid = uigridlayout(app.Prepare);
            app.LoadButton = slrealtime.ui.control.LoadButton(app.PrepareGrid);
            app.LoadButton.ShowLoadedApplication = false;
            app.RunOnTarget = uipanel(app.ToolstripPanel);
            app.RunOnTargetGrid = uigridlayout(app.RunOnTarget);
            app.StopTimeEditField = slrealtime.ui.control.StopTimeEditField(app.RunOnTargetGrid);
            app.StartStopButton = slrealtime.ui.control.StartStopButton(app.RunOnTargetGrid);
            app.StopTimeLabel = uilabel(app.RunOnTargetGrid);
            app.StopTimeLabel.Text = 'Stop Time';
            app.SystemLogPanel = uipanel(app.ToolstripPanel);
            app.SystemLogGrid = uigridlayout(app.SystemLogPanel);
            app.SystemLog = slrealtime.ui.control.SystemLog(app.SystemLogGrid);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appForFOC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end